classdef covid_predict < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FORMCOVIDPanel                  matlab.ui.container.Panel
        BreathingproblemDropDownLabel   matlab.ui.control.Label
        BreathingproblemDropDown        matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        FeverDropDown                   matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DryCoughDropDown                matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        SoreThroatDropDown              matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        RunningNoseDropDown             matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        AsthmaDropDown                  matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        ChronicLungDiseaseDropDown      matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        HeadacheDropDown                matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        HeartDiseaseDropDown            matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        DiabetesDropDown                matlab.ui.control.DropDown
        Label_10                        matlab.ui.control.Label
        HyperTensionDropDown            matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        FatigueDropDown                 matlab.ui.control.DropDown
        Label_12                        matlab.ui.control.Label
        GastrointestinalDropDown        matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        AbroadtravelDropDown            matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        ContactwithCOVIDPatientDropDown  matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        AttendedLargeGatheringDropDown  matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        VisitedPublicExposedPlacesDropDown  matlab.ui.control.DropDown
        Label_17                        matlab.ui.control.Label
        FamilyworkinginPublicExposedPlaceDropDown  matlab.ui.control.DropDown
        Label_18                        matlab.ui.control.Label
        WearingMasksDropDown            matlab.ui.control.DropDown
        Label_19                        matlab.ui.control.Label
        SanitizationfromMarketDropDown  matlab.ui.control.DropDown
        PredictButton                   matlab.ui.control.StateButton
        ProbabilityEditFieldLabel       matlab.ui.control.Label
        ProbabilityEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
       x = ones(1,21);
       theta = [-4.1292 1.7318 1.5109 1.9055 2.0421 -0.6689 0.0324 -0.3946 -0.3717 -0.2381  0.0708 -0.1174 -0.4549 -0.2735 3.9289 1.6494 3.3850 -0.0420 0.6143 0.2891 0.1728];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ProbabilitCOVIDTextAreaValueChanged(app, event)


        end

        % Value changed function: PredictButton
        function ProbabilityEditFieldValueChanged(app, event)


            app.x(2) = app.BreathingproblemDropDown.Value;
            app.x(3) = app.FeverDropDown.Value;
            app.x(4) = app.DryCoughDropDown.Value;
            app.x(5) = app.SoreThroatDropDown.Value;
            app.x(6) = app.RunningNoseDropDown.Value;
            app.x(7) = app.AsthmaDropDown.Value;
            app.x(8) = app.ChronicLungDiseaseDropDown.Value;
            app.x(9) = app.HeadacheDropDown.Value;
            app.x(10) = app.HeartDiseaseDropDown.Value;
            app.x(11) = app.DiabetesDropDown.Value;
            app.x(12) = app.HyperTensionDropDown.Value;
            app.x(13) = app.FatigueDropDown.Value;
            app. x(14) = app.GastrointestinalDropDown.Value;
            app. x(15) = app.AbroadtravelDropDown.Value;
            app. x(16) = app.ContactwithCOVIDPatientDropDown.Value;
            app. x(17) = app.AttendedLargeGatheringDropDown.Value;
            app.x(18) = app.VisitedPublicExposedPlacesDropDown.Value;
            app.x(19) = app.FamilyworkinginPublicExposedPlaceDropDown.Value;
            app.x(20) = app.WearingMasksDropDown.Value;
            app.x(21) = app.SanitizationfromMarketDropDown.Value;


            z =  app.theta * app.x';
            prob = 1./(1+exp(-z));
            app.ProbabilityEditField.Value = prob;


        end

        % Value changed function: 
        % FamilyworkinginPublicExposedPlaceDropDown
        function FamilyworkinginPublicExposedPlaceDropDownValueChanged(app, event)
            app.x(19) = app.FamilyworkinginPublicExposedPlaceDropDown.Value;
            
        end

        % Value changed function: SanitizationfromMarketDropDown
        function SanitizationfromMarketDropDownValueChanged(app, event)
            app.x(21) = app.SanitizationfromMarketDropDown.Value;
            
        end

        % Value changed function: WearingMasksDropDown
        function WearingMasksDropDownValueChanged(app, event)
            app.x(20) = app.WearingMasksDropDown.Value;
            
        end

        % Value changed function: VisitedPublicExposedPlacesDropDown
        function VisitedPublicExposedPlacesDropDownValueChanged(app, event)
            app.x(18) = app.VisitedPublicExposedPlacesDropDown.Value;
   
        end

        % Value changed function: AttendedLargeGatheringDropDown
        function AttendedLargeGatheringDropDownValueChanged(app, event)
            app.x(17) = app.AttendedLargeGatheringDropDown.Value;
            
        end

        % Callback function
        function DropDown_15ValueChanged(app, event)
            app.x(16) = app.ContactwithCOVIDPatientDropDown.Value;
            
        end

        % Value changed function: AbroadtravelDropDown
        function AbroadtravelDropDownValueChanged(app, event)
            app.x(15) = app.AbroadtravelDropDown.Value;
            
        end

        % Value changed function: GastrointestinalDropDown
        function GastrointestinalDropDownValueChanged(app, event)
            app.x(14) = app.GastrointestinalDropDown.Value;
            
        end

        % Value changed function: FatigueDropDown
        function FatigueDropDownValueChanged(app, event)
            app.x(13) = app.FatigueDropDown.Value;
            
        end

        % Value changed function: HyperTensionDropDown
        function HyperTensionDropDownValueChanged(app, event)
            app.x(12) = app.HyperTensionDropDown.Value;
            
        end

        % Value changed function: DiabetesDropDown
        function DiabetesDropDownValueChanged(app, event)
            app.x(11) = app.DiabetesDropDown.Value;
            
        end

        % Value changed function: HeadacheDropDown
        function HeadacheDropDownValueChanged(app, event)
            app.x(9) = app.HeadacheDropDown.Value;
            
        end

        % Value changed function: ContactwithCOVIDPatientDropDown
        function ContactwithCOVIDPatientDropDownValueChanged2(app, event)
            app.x(16) = app.ContactwithCOVIDPatientDropDown.Value;
            
        end

        % Value changed function: BreathingproblemDropDown
        function BreathingproblemDropDownValueChanged(app, event)
            app.x(2) = app.BreathingproblemDropDown.Value;
            
        end

        % Value changed function: FeverDropDown
        function FeverDropDownValueChanged(app, event)
            app.x(3) = app.FeverDropDown.Value;
            
        end

        % Value changed function: DryCoughDropDown
        function DryCoughDropDownValueChanged(app, event)
            app.x(4) = app.DryCoughDropDown.Value;
            
        end

        % Value changed function: SoreThroatDropDown
        function SoreThroatDropDownValueChanged(app, event)
            app.x(5) = app.SoreThroatDropDown.Value;
            
        end

        % Value changed function: RunningNoseDropDown
        function RunningNoseDropDownValueChanged(app, event)
            app.x(6) = app.RunningNoseDropDown.Value;
            
        end

        % Value changed function: AsthmaDropDown
        function AsthmaDropDownValueChanged(app, event)
            app.x(7) = app.AsthmaDropDown.Value;
            
        end

        % Value changed function: ChronicLungDiseaseDropDown
        function ChronicLungDiseaseDropDownValueChanged(app, event)
            app.x(8) = app.ChronicLungDiseaseDropDown.Value;
            
        end

        % Value changed function: HeartDiseaseDropDown
        function HeartDiseaseDropDownValueChanged(app, event)
              app.x(10) = app.HeartDiseaseDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FORMCOVIDPanel = uipanel(app.UIFigure);
            app.FORMCOVIDPanel.Title = 'FORM COVID';
            app.BreathingproblemDropDownLabel = uilabel(app.FORMCOVIDPanel);
            app.BreathingproblemDropDownLabel.Text = 'Breathing problem';
            app.BreathingproblemDropDown = uidropdown(app.FORMCOVIDPanel);
            app.BreathingproblemDropDown.Items = {'Si', 'No'};
            app.BreathingproblemDropDown.ItemsData = [1 0];
            app.BreathingproblemDropDown.ValueChangedFcn = createCallbackFcn(app, @BreathingproblemDropDownValueChanged, true);
            app.BreathingproblemDropDown.Value = 0;
            app.Label = uilabel(app.FORMCOVIDPanel);
            app.Label.Text = 'Fever';
            app.FeverDropDown = uidropdown(app.FORMCOVIDPanel);
            app.FeverDropDown.Items = {'Si', 'No'};
            app.FeverDropDown.ItemsData = [1 0];
            app.FeverDropDown.ValueChangedFcn = createCallbackFcn(app, @FeverDropDownValueChanged, true);
            app.FeverDropDown.Value = 0;
            app.Label_2 = uilabel(app.FORMCOVIDPanel);
            app.Label_2.Text = 'Dry Cough';
            app.DryCoughDropDown = uidropdown(app.FORMCOVIDPanel);
            app.DryCoughDropDown.Items = {'Si', 'No'};
            app.DryCoughDropDown.ItemsData = [1 0];
            app.DryCoughDropDown.ValueChangedFcn = createCallbackFcn(app, @DryCoughDropDownValueChanged, true);
            app.DryCoughDropDown.Value = 0;
            app.Label_3 = uilabel(app.FORMCOVIDPanel);
            app.Label_3.Text = 'Sore Throat';
            app.SoreThroatDropDown = uidropdown(app.FORMCOVIDPanel);
            app.SoreThroatDropDown.Items = {'Si', 'No'};
            app.SoreThroatDropDown.ItemsData = [1 0];
            app.SoreThroatDropDown.ValueChangedFcn = createCallbackFcn(app, @SoreThroatDropDownValueChanged, true);
            app.SoreThroatDropDown.Value = 0;
            app.Label_4 = uilabel(app.FORMCOVIDPanel);
            app.Label_4.Text = 'Running Nose';
            app.RunningNoseDropDown = uidropdown(app.FORMCOVIDPanel);
            app.RunningNoseDropDown.Items = {'Si', 'No'};
            app.RunningNoseDropDown.ItemsData = [1 0];
            app.RunningNoseDropDown.ValueChangedFcn = createCallbackFcn(app, @RunningNoseDropDownValueChanged, true);
            app.RunningNoseDropDown.Value = 0;
            app.Label_5 = uilabel(app.FORMCOVIDPanel);
            app.Label_5.Text = 'Asthma';
            app.AsthmaDropDown = uidropdown(app.FORMCOVIDPanel);
            app.AsthmaDropDown.Items = {'Si', 'No'};
            app.AsthmaDropDown.ItemsData = [1 0];
            app.AsthmaDropDown.ValueChangedFcn = createCallbackFcn(app, @AsthmaDropDownValueChanged, true);
            app.AsthmaDropDown.Value = 0;
            app.Label_6 = uilabel(app.FORMCOVIDPanel);
            app.Label_6.Text = 'Chronic Lung Disease';
            app.ChronicLungDiseaseDropDown = uidropdown(app.FORMCOVIDPanel);
            app.ChronicLungDiseaseDropDown.Items = {'Si', 'No'};
            app.ChronicLungDiseaseDropDown.ItemsData = [1 0];
            app.ChronicLungDiseaseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChronicLungDiseaseDropDownValueChanged, true);
            app.ChronicLungDiseaseDropDown.Value = 0;
            app.Label_7 = uilabel(app.FORMCOVIDPanel);
            app.Label_7.Text = 'Headache';
            app.HeadacheDropDown = uidropdown(app.FORMCOVIDPanel);
            app.HeadacheDropDown.Items = {'Si', 'No'};
            app.HeadacheDropDown.ItemsData = [1 0];
            app.HeadacheDropDown.ValueChangedFcn = createCallbackFcn(app, @HeadacheDropDownValueChanged, true);
            app.HeadacheDropDown.Value = 0;
            app.Label_8 = uilabel(app.FORMCOVIDPanel);
            app.Label_8.Text = 'Heart Disease';
            app.HeartDiseaseDropDown = uidropdown(app.FORMCOVIDPanel);
            app.HeartDiseaseDropDown.Items = {'Si', 'No'};
            app.HeartDiseaseDropDown.ItemsData = [1 0];
            app.HeartDiseaseDropDown.ValueChangedFcn = createCallbackFcn(app, @HeartDiseaseDropDownValueChanged, true);
            app.HeartDiseaseDropDown.Value = 0;
            app.Label_9 = uilabel(app.FORMCOVIDPanel);
            app.Label_9.Text = 'Diabetes';
            app.DiabetesDropDown = uidropdown(app.FORMCOVIDPanel);
            app.DiabetesDropDown.Items = {'Si', 'No'};
            app.DiabetesDropDown.ItemsData = [1 0];
            app.DiabetesDropDown.ValueChangedFcn = createCallbackFcn(app, @DiabetesDropDownValueChanged, true);
            app.DiabetesDropDown.Value = 0;
            app.Label_10 = uilabel(app.FORMCOVIDPanel);
            app.Label_10.Text = 'HyperTension';
            app.HyperTensionDropDown = uidropdown(app.FORMCOVIDPanel);
            app.HyperTensionDropDown.Items = {'Si', 'No'};
            app.HyperTensionDropDown.ItemsData = [1 0];
            app.HyperTensionDropDown.ValueChangedFcn = createCallbackFcn(app, @HyperTensionDropDownValueChanged, true);
            app.HyperTensionDropDown.Value = 0;
            app.Label_11 = uilabel(app.FORMCOVIDPanel);
            app.Label_11.Text = 'Fatigue';
            app.FatigueDropDown = uidropdown(app.FORMCOVIDPanel);
            app.FatigueDropDown.Items = {'Si', 'No'};
            app.FatigueDropDown.ItemsData = [1 0];
            app.FatigueDropDown.ValueChangedFcn = createCallbackFcn(app, @FatigueDropDownValueChanged, true);
            app.FatigueDropDown.Value = 0;
            app.Label_12 = uilabel(app.FORMCOVIDPanel);
            app.Label_12.Text = 'Gastrointestinal';
            app.GastrointestinalDropDown = uidropdown(app.FORMCOVIDPanel);
            app.GastrointestinalDropDown.Items = {'Si', 'No'};
            app.GastrointestinalDropDown.ItemsData = [1 0];
            app.GastrointestinalDropDown.ValueChangedFcn = createCallbackFcn(app, @GastrointestinalDropDownValueChanged, true);
            app.GastrointestinalDropDown.Value = 0;
            app.Label_13 = uilabel(app.FORMCOVIDPanel);
            app.Label_13.Text = 'Abroad travel';
            app.AbroadtravelDropDown = uidropdown(app.FORMCOVIDPanel);
            app.AbroadtravelDropDown.Items = {'Si', 'No'};
            app.AbroadtravelDropDown.ItemsData = [1 0];
            app.AbroadtravelDropDown.ValueChangedFcn = createCallbackFcn(app, @AbroadtravelDropDownValueChanged, true);
            app.AbroadtravelDropDown.Value = 0;
            app.Label_14 = uilabel(app.FORMCOVIDPanel);
            app.Label_14.Text = 'Contact with COVID Patient';
            app.ContactwithCOVIDPatientDropDown = uidropdown(app.FORMCOVIDPanel);
            app.ContactwithCOVIDPatientDropDown.Items = {'Si', 'No'};
            app.ContactwithCOVIDPatientDropDown.ItemsData = [1 0];
            app.ContactwithCOVIDPatientDropDown.ValueChangedFcn = createCallbackFcn(app, @ContactwithCOVIDPatientDropDownValueChanged2, true);
            app.ContactwithCOVIDPatientDropDown.Value = 0;
            app.Label_15 = uilabel(app.FORMCOVIDPanel);
            app.Label_15.Text = 'Attended Large Gathering';
            app.AttendedLargeGatheringDropDown = uidropdown(app.FORMCOVIDPanel);
            app.AttendedLargeGatheringDropDown.Items = {'Si', 'No'};
            app.AttendedLargeGatheringDropDown.ItemsData = [1 0];
            app.AttendedLargeGatheringDropDown.ValueChangedFcn = createCallbackFcn(app, @AttendedLargeGatheringDropDownValueChanged, true);
            app.AttendedLargeGatheringDropDown.Value = 0;
            app.Label_16 = uilabel(app.FORMCOVIDPanel);
            app.Label_16.Text = 'Visited Public Exposed Places';
            app.VisitedPublicExposedPlacesDropDown = uidropdown(app.FORMCOVIDPanel);
            app.VisitedPublicExposedPlacesDropDown.Items = {'Si', 'No'};
            app.VisitedPublicExposedPlacesDropDown.ItemsData = [1 0];
            app.VisitedPublicExposedPlacesDropDown.ValueChangedFcn = createCallbackFcn(app, @VisitedPublicExposedPlacesDropDownValueChanged, true);
            app.VisitedPublicExposedPlacesDropDown.Value = 0;
            app.Label_17 = uilabel(app.FORMCOVIDPanel);
            app.Label_17.Text = 'Family working in Public Exposed Place';
            app.FamilyworkinginPublicExposedPlaceDropDown = uidropdown(app.FORMCOVIDPanel);
            app.FamilyworkinginPublicExposedPlaceDropDown.Items = {'Si', 'No'};
            app.FamilyworkinginPublicExposedPlaceDropDown.ItemsData = [1 0];
            app.FamilyworkinginPublicExposedPlaceDropDown.ValueChangedFcn = createCallbackFcn(app, @FamilyworkinginPublicExposedPlaceDropDownValueChanged, true);
            app.FamilyworkinginPublicExposedPlaceDropDown.Value = 0;
            app.Label_18 = uilabel(app.FORMCOVIDPanel);
            app.Label_18.Text = 'Wearing Masks';
            app.WearingMasksDropDown = uidropdown(app.FORMCOVIDPanel);
            app.WearingMasksDropDown.Items = {'Si', 'No'};
            app.WearingMasksDropDown.ItemsData = [1 0];
            app.WearingMasksDropDown.ValueChangedFcn = createCallbackFcn(app, @WearingMasksDropDownValueChanged, true);
            app.WearingMasksDropDown.Value = 0;
            app.Label_19 = uilabel(app.FORMCOVIDPanel);
            app.Label_19.Text = 'Sanitization from Market';
            app.SanitizationfromMarketDropDown = uidropdown(app.FORMCOVIDPanel);
            app.SanitizationfromMarketDropDown.Items = {'Si', 'No'};
            app.SanitizationfromMarketDropDown.ItemsData = [1 0];
            app.SanitizationfromMarketDropDown.ValueChangedFcn = createCallbackFcn(app, @SanitizationfromMarketDropDownValueChanged, true);
            app.SanitizationfromMarketDropDown.Value = 0;
            app.PredictButton = uibutton(app.UIFigure, 'state');
            app.PredictButton.ValueChangedFcn = createCallbackFcn(app, @ProbabilityEditFieldValueChanged, true);
            app.PredictButton.Text = 'Predict';
            app.ProbabilityEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilityEditFieldLabel.Text = 'Probability';
            app.ProbabilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProbabilityEditField.ValueDisplayFormat = '%.2f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_predict

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end